classdef DHConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        CalculateDenavitHartenbergButton  matlab.ui.control.Button
        RemoveLastLinksButton           matlab.ui.control.Button
        BasiskoordinatensystemLabel     matlab.ui.control.Label
        QuaternionenLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DenavitHartenbergParameterTab   matlab.ui.container.Tab
        DenavitTable                    matlab.ui.control.Table
        BasiskoorsysundQuaternionenTab  matlab.ui.container.Tab
        KoorsysUndQuationen             matlab.ui.control.Table
        MoveITTab                       matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        ShowPlotButton                  matlab.ui.control.Button
        AddLinksButton                  matlab.ui.control.Button
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        q2EditField                     matlab.ui.control.NumericEditField
        q2EditFieldLabel                matlab.ui.control.Label
        q3EditField                     matlab.ui.control.NumericEditField
        q3EditFieldLabel                matlab.ui.control.Label
        q1EditField                     matlab.ui.control.NumericEditField
        q1EditFieldLabel                matlab.ui.control.Label
        q0EditField                     matlab.ui.control.NumericEditField
        q0EditFieldLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        Numbers_I               % Anzahl der Links
        BasisVektorsList        % Liste von Basiskoordinatensystem
        QuaternionZeroList      % Liste der q0
        QuaternionVektorList    % Lister der q vektor
        index = 0;
        InputData = [];
        OutputData = [];
        aList
        dList
        alphaList
        tethaList
        moving
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddLinksButton
        function AddLinksButtonPushed(app, event)
            app.index = app.index + 1;
            % Add to the current properties
            app.BasisVektorsList(app.index,:) = [app.xEditField.Value, app.yEditField.Value, app.zEditField.Value];
            if checkPlausibilityBasiskoorsystem(app.BasisVektorsList) == 0
                warndlg("Link Objekte können nicht miteinander verbunden werden- Bitte Vektoren überprüfen und erneut eingeben!", "Warning");
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.index = app.index - 1;
                return
            end
            
            app.QuaternionZeroList(app.index) = app.q0EditField.Value;
            app.QuaternionVektorList(app.index,:) = [app.q1EditField.Value, app.q2EditField.Value, app.q3EditField.Value];
            [Quaternion_plausible, msg] = checkPlausibilityQuaternion(app.QuaternionZeroList, app.QuaternionVektorList);
            if Quaternion_plausible == 0
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.QuaternionZeroList = app.QuaternionZeroList(1:end-1,:);
                app.QuaternionVektorList = app.QuaternionVektorList(1:end-1,:);
                warndlg(msg, "Warning!");
                app.index = app.index - 1;
                return
            end
            
            app.InputData(app.index,:) = [app.BasisVektorsList(app.index,:) app.QuaternionZeroList(app.index) app.QuaternionVektorList(app.index,:)];
            app.KoorsysUndQuationen.Data = app.InputData;
        end

        % Button pushed function: RemoveLastLinksButton
        function RemoveLastLinksButtonPushed(app, event)
            if app.index == 0
                warndlg("There is no row to be remove!","Warning");
            else
                app.BasisVektorsList = app.BasisVektorsList(1:end-1,:);
                app.QuaternionZeroList = app.QuaternionZeroList(1:end-1,:);
                app.QuaternionVektorList = app.QuaternionVektorList(1:end-1,:);
                app.InputData = app.InputData(1:end-1,:);
                app.KoorsysUndQuationen.Data = app.InputData;
                app.index = app.index - 1; 
            end
        end

        % Button pushed function: CalculateDenavitHartenbergButton
        function CalculateDenavitHartenbergButtonPushed(app, event)
            % do the callculation here
            if app.index == 0
                warndlg("Please input the basis coordinate and the quaternion first!","Warning");
            else
                [app.tethaList, app.dList, app.aList, app.alphaList] = calculateDenavitHartenbergParam(app.BasisVektorsList, app.QuaternionZeroList, app.QuaternionVektorList);
                app.OutputData = [app.tethaList, app.dList, app.aList, app.alphaList];
                app.DenavitTable.Data = app.OutputData; 
                ddvalue = app.DropDown.Items;
                for i = 1:app.index
                    ddvalue{i+1} = convertStringsToChars('L' +string(i));
                end
            end
            app.DropDown.Items = ddvalue;
            
        end

        % Button pushed function: ShowPlotButton
        function ShowPlotButtonPushed(app, event)
            if app.index < 2
                warndlg("2 Links are needed to plot!");
            else
                if isempty(app.OutputData)
                    warndlg("Calculate DH parameter first!");
                else
                    app.moving = zeros(1, app.index);
                    showRobotInPlot(app.tethaList, app.dList, app.aList, app.alphaList, app.moving);
                end
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.DropDown.Value ~= "none"
                x = find(contains(app.DropDown.Items, app.DropDown.Value)) - 1;
                app.moving(x) = changingValue;
                showRobotInPlot(app.tethaList, app.dList, app.aList, app.alphaList, app.moving); 
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if app.DropDown.Value ~= "none"
                x = find(contains(app.DropDown.Items, app.DropDown.Value)) - 1;
                app.moving(x) = value;
                showRobotInPlot(app.tethaList, app.dList, app.aList, app.alphaList, app.moving); 
            end
        end

        % Button down function: MoveITTab
        function MoveITTabButtonDown(app, event)
            hold on
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q0EditFieldLabel = uilabel(app.UIFigure);
            app.q0EditFieldLabel.Text = 'q0';
            app.q0EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddLinksButton = uibutton(app.UIFigure, 'push');
            app.AddLinksButton.ButtonPushedFcn = createCallbackFcn(app, @AddLinksButtonPushed, true);
            app.AddLinksButton.Text = 'Add Links';
            app.ShowPlotButton = uibutton(app.UIFigure, 'push');
            app.ShowPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlotButtonPushed, true);
            app.ShowPlotButton.Text = 'Show Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DenavitHartenbergParameterTab = uitab(app.TabGroup);
            app.DenavitHartenbergParameterTab.Title = 'Denavit Hartenberg Parameter';
            app.DenavitTable = uitable(app.DenavitHartenbergParameterTab);
            app.DenavitTable.ColumnName = {'tetha'; 'd'; 'a'; 'alpha'};
            app.DenavitTable.RowName = {};
            app.BasiskoorsysundQuaternionenTab = uitab(app.TabGroup);
            app.BasiskoorsysundQuaternionenTab.Title = 'Basiskoor.sys und Quaternionen';
            app.KoorsysUndQuationen = uitable(app.BasiskoorsysundQuaternionenTab);
            app.KoorsysUndQuationen.ColumnName = {'x'; 'y'; 'z'; 'q0'; 'q1'; 'q2'; 'q3'};
            app.KoorsysUndQuationen.RowName = {};
            app.MoveITTab = uitab(app.TabGroup);
            app.MoveITTab.Title = 'Move IT';
            app.MoveITTab.ButtonDownFcn = createCallbackFcn(app, @MoveITTabButtonDown, true);
            app.SliderLabel = uilabel(app.MoveITTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.MoveITTab);
            app.Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.DropDownLabel = uilabel(app.MoveITTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.MoveITTab);
            app.DropDown.Items = {'none'};
            app.DropDown.Value = 'none';
            app.QuaternionenLabel = uilabel(app.UIFigure);
            app.QuaternionenLabel.Text = 'Quaternionen';
            app.BasiskoordinatensystemLabel = uilabel(app.UIFigure);
            app.BasiskoordinatensystemLabel.Text = 'Basiskoordinatensystem';
            app.RemoveLastLinksButton = uibutton(app.UIFigure, 'push');
            app.RemoveLastLinksButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastLinksButtonPushed, true);
            app.RemoveLastLinksButton.Text = 'Remove Last Links';
            app.CalculateDenavitHartenbergButton = uibutton(app.UIFigure, 'push');
            app.CalculateDenavitHartenbergButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDenavitHartenbergButtonPushed, true);
            app.CalculateDenavitHartenbergButton.Text = 'Calculate Denavit Hartenberg';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'HKA_MMT_Bildmarke-h_RGB.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Robotertechnik Hausarbeit SS 2021 Gruppe 8'; 'Till Gostner, Muhammad Hanif, Nico Mayer'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DHConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end